classdef ViSATUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MaximizewindowthenpressLabel  matlab.ui.control.Label
        Instructions                  matlab.ui.control.Label
        PhotodiodeCheckBox            matlab.ui.control.CheckBox
        BlockInfo                     matlab.ui.container.Panel
        IDEditField                   matlab.ui.control.EditField
        IDEditFieldLabel              matlab.ui.control.Label
        SetDropDown                   matlab.ui.control.DropDown
        SetDropDownLabel              matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        ViSATTaskLabel                matlab.ui.control.Label
        display                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stimulusImages % Description
        answerImages
        fixationImage
        mturkData
        controlData
        ptdir
        userAnswers
        currenttrial
        DurationsFixStimAns
        trialtimestamps % timepoints of each step in trial: fixation on/off, stim on/off, answer on/off (diff of these two is RT)
        XYclickStimulus % XY coordinate of click during stimulus stage
        XYclickAnswer % XY coordinate of click during answer stage
        blockfilename % Description
        csvFilename
        summaryFilename
        blockDateTime % Description
        %photodiodepixels ={1:150,621:720}; % Description
    end
    
    methods (Access = private)
        
        
      %%% 1. FIXATION step
        function results = fixationFunction(app)
          app.trialtimestamps{app.currenttrial,1}=datestr(now,'dd-mm-yyyy HH:MM:SS FFF'); % timestamp: fixation point displayed
            f=app.fixationImage{1}; %f(:,:,:)=f(1,1,1); 
            if app.PhotodiodeCheckBox.Value==true; app.ViSATTaskLabel.BackgroundColor=[1 1 1]; end %f(app.photodiodepixels{1},app.photodiodepixels{2},:)=f(1,1,1)*1; end
            image(app.display,f); 
            tic
            pause(2+rand(1,1)); % 2-3 sec fixation delay (random jitter)
            if ~isvalid(app)
                msgbox('App exited during a block. Any completed trials still saved'); 
                return; 
            end %in case app closed (block stopped)
          app.trialtimestamps{app.currenttrial,2}=datestr(now,'dd-mm-yyyy HH:MM:SS FFF'); % timestamp: fixation point done displaying
        end
        
        
      %%% 2. STIMULUS step
        function results = stimulusFunction(app)
          f=app.stimulusImages{app.currenttrial}; 
          if app.PhotodiodeCheckBox.Value==true; app.ViSATTaskLabel.BackgroundColor=[.5 .5 .5]; end %f(app.photodiodepixels{1},app.photodiodepixels{2},:)=f(1,1,1)*.5; end
            image(app.display,f,'ButtonDownFcn',@userinput);
          app.trialtimestamps{app.currenttrial,3}=datestr(now,'dd-mm-yyyy HH:MM:SS FFF'); % timestamp: stimulus displayed
          app.DurationsFixStimAns(app.currenttrial,1)=toc; 
              tic
            uiwait(app.UIFigure);
            if ~isvalid(app)
                msgbox('App exited during a block. Any completed trials still saved');
                return;
            end %in case app closed (block stopped)
            %see #wait-for-click function below, then continues to 3. ANSWER step
          
          % #wait-for-click function
            function []=userinput(~,eventData) %% when user input occurs, get click coordinates
             % make sure they click near the stimulus (to help confirm they looked at it
               if eventData.IntersectionPoint(2)<=320 && ...
                  eventData.IntersectionPoint(1)>=1 && ...
                  eventData.IntersectionPoint(1)<=720; 
             % and if so then document x and y coordinates of the click
                app.DurationsFixStimAns(app.currenttrial,2)=toc; % get stimulus step reaction time
                app.trialtimestamps{app.currenttrial,4}=datestr(now,'dd-mm-yyyy HH:MM:SS FFF'); % timestamp: user clicked to show answer choices
                app.XYclickStimulus(app.currenttrial,1:2)= eventData.IntersectionPoint(1:2);
                uiresume(app.UIFigure)
               end
            end
        end
        
        
      %%% 3. ANSWER step
        function results = answerFunction(app)
          app.trialtimestamps{app.currenttrial,5}=datestr(now,'dd-mm-yyyy HH:MM:SS FFF'); % timestamp: answer choices displayed
          f=app.answerImages{app.currenttrial}; 
          if app.PhotodiodeCheckBox.Value==true; app.ViSATTaskLabel.BackgroundColor=[0 0 0]; end %f(app.photodiodepixels{1},app.photodiodepixels{2},:)=f(1,1,1)*0; end
            image(app.display,f,'ButtonDownFcn',@userinput);
              tic
            uiwait(app.UIFigure,20);
            if ~isvalid(app)
                msgbox('App exited during a block. Any completed trials still saved');
                return;
            end %in case app closed (block stopped)
              %see #wait-for-click function below, then continues below
            
            
          %Check coordinates to get what their answer was,
          %    Note answer panel layout: A B
          %                              C D
          x=app.XYclickAnswer(app.currenttrial,1);
          y=app.XYclickAnswer(app.currenttrial,2);
          if y>=321 && y<=640 % upper panel answers (A, B)
              if     x>=  1 && x<=360; app.userAnswers{app.currenttrial,1}='A'; % left panel
              elseif x>=361 && x<=720; app.userAnswers{app.currenttrial,1}='B'; % right panel
              end
          elseif y>=641 && y<=960 % lower panel answers (C, D)
              if     x>=  1 && x<=360; app.userAnswers{app.currenttrial,1}='C'; % left panel
              elseif x>=361 && x<=720; app.userAnswers{app.currenttrial,1}='D'; % right panel
              end
          end
          
          if isempty(app.userAnswers{app.currenttrial,1});
              return
          end


          % #wait-for-click function
            function []=userinput(~,eventData) %% when user input occurs, get click coordinates

             % make sure they click on an answer, and not the bordering lines nor the stimulus
              valid_answer=false;
              x=eventData.IntersectionPoint(1);
              y=eventData.IntersectionPoint(2);
              if y>=340 && y<=620 % upper panel answers (A, B)
                  if     x>= 20 && x<=340; valid_answer=true; % left panel
                  elseif x>=380 && x<=700; valid_answer=true; % right panel
                  end
              elseif y>=660 && y<=940 % lower panel answers (C, D)
                  if     x>= 20 && x<=340; valid_answer=true; % left panel
                  elseif x>=380 && x<=700; valid_answer=true; % right panel
                  end
              end
              
             % and if so then document x and y coordinates of the click
               if valid_answer==true
                app.DurationsFixStimAns(app.currenttrial,3)=toc; % get answer step reaction time
                app.trialtimestamps{app.currenttrial,6}=datestr(now,'dd-mm-yyyy HH:MM:SS FFF'); % timestamp: answer selected
                app.XYclickAnswer(app.currenttrial,1:2)= eventData.IntersectionPoint(1:2);
                f=255*ones(960,720,3,'uint8');
                if app.PhotodiodeCheckBox.Value==true; app.ViSATTaskLabel.BackgroundColor=[1 1 1]; end %f(app.photodiodepixels{1},app.photodiodepixels{2},:)=f(1,1,1)*1; end
                image(app.display,f); %back to white screen
                uiresume(app.UIFigure)
               else % nothing will happen if they didn't click on an actual answer yet
               end
            end
        end
        
      %%% 4. SAVE step (save all data after each trial)
        function results = saveFunction(app)
            %RTinSec=app.DurationsFixStimAns(app.currenttrial,1)=app.trialtimestamps{app.currenttrial,6}-app.trialtimestamps{app.currenttrial,5}; %calculate RT in ms
            XYclickAnswer=app.XYclickAnswer;
            XYclickStimulus=app.XYclickStimulus;
            trialtimestamps=app.trialtimestamps;
            userAnswers=app.userAnswers;
            DurationsFixStimAns=app.DurationsFixStimAns;
            photodiodeEnabled=app.PhotodiodeCheckBox.Value;
            participantID=app.IDEditField.Value;
            block=app.SetDropDown.Value;
            blockDateTime=app.blockDateTime;
            blockfilename=app.blockfilename;
            save(app.blockfilename,'XYclickAnswer','XYclickStimulus','DurationsFixStimAns','trialtimestamps','userAnswers','photodiodeEnabled','participantID','block','blockDateTime','blockfilename');
            if app.currenttrial > 3
                ntrials = app.currenttrial - 3;
                trial_id = nan(25, 1);
                first_trial = str2double(block)*25-24;
                trial_id(1:ntrials) = first_trial:first_trial+ntrials-1;
                answer=app.userAnswers(4:end);
                stimulus_response_time = app.DurationsFixStimAns(4:end, 2);
                answer_response_time = app.DurationsFixStimAns(4:end, 3);
                consensus_answer = cell(25, 1);
                consensus_answer(1:ntrials) = app.mturkData.consensus_answer(trial_id(1:app.currenttrial - 3));
                pcons_consensus_answer = nan(25, 1);
                pcons_consensus_answer(1:ntrials) = app.mturkData.pcons_consensus_answer(trial_id(1:app.currenttrial - 3));
                pcons_answer = nan(25, 1);
                correct = nan(25,1);
                for i = 1:ntrials
                    pcons_answer(i) = app.mturkData.pcons_all(trial_id(i), answer{i} - 'A' + 1)*100;
                    correct(i) = strcmpi(answer{i}, consensus_answer{i});
                end
                csv_table = table(trial_id, answer, consensus_answer, correct, pcons_answer, pcons_consensus_answer, stimulus_response_time, answer_response_time);
                writetable(csv_table, app.csvFilename);
            end
        end
        
        function results = computeSummary(app)
            csvFilenames = {
                fullfile(app.ptdir, 'set1.csv');
                fullfile(app.ptdir, 'set2.csv');
                fullfile(app.ptdir, 'set3.csv');
                fullfile(app.ptdir, 'set4.csv');
            };
            trial_set = [
                "set_1";
                "set_2";
                "set_3";
                "set_4";
                "all_sets";
            ];
            accuracy = nan(5, 1);
            accuracy_percentile = nan(5, 1);
            stimulus_response_time = nan(5, 1);
            stimulus_response_time_percentile = nan(5, 1);
            answer_response_time = nan(5, 1);
            answer_response_time_percentile = nan(5, 1);
            for i=1:length(csvFilenames)
                file = csvFilenames{i};
                if exist(file, 'file')
                    setTable = readtable(file);
                    correct = 0;
                    stim_rt = 0;
                    ans_rt = 0;
                    count = 0;
                    for r=1:height(setTable)
                        if ~isnan(setTable.stimulus_response_time(r))
                            correct = correct + setTable.correct(r);
                            stim_rt = stim_rt + setTable.stimulus_response_time(r);
                            ans_rt = ans_rt + setTable.answer_response_time(r);
                            count = count + 1;
                        end
                    end
                    accuracy(i) = correct /  count * 100;
                    accuracy_percentile(i) = computePercentile(app, accuracy(i), app.controlData.accuracy);
                    stimulus_response_time(i) = stim_rt / count;
                    stimulus_response_time_percentile(i) = computePercentile(app, stimulus_response_time(i),app.controlData.stim_response_times);
                    answer_response_time(i) = ans_rt /count;
                    answer_response_time_percentile(i) = computePercentile(app, answer_response_time(i), app.controlData.ans_response_times);
                end
            end
            nsets = length(accuracy(~isnan(accuracy)));
            accuracy(5) = sum(accuracy(1:4), 'omitnan')/nsets;
            accuracy_percentile(5) = computePercentile(app, accuracy(5), app.controlData.accuracy);
            stimulus_response_time(5) = sum(stimulus_response_time(1:4), 'omitnan')/nsets;
            stimulus_response_time_percentile(5) = computePercentile(app, stimulus_response_time(5),app.controlData.stim_response_times);
            answer_response_time(5) = sum(answer_response_time(1:4), 'omitnan')/nsets;
            answer_response_time_percentile(5) = computePercentile(app, answer_response_time(5), app.controlData.ans_response_times);

            summary = table(trial_set, accuracy, accuracy_percentile, ...
                            stimulus_response_time, stimulus_response_time_percentile, ...
                            answer_response_time, answer_response_time_percentile);
            writetable(summary, app.summaryFilename);
        end

        function percentile = computePercentile(~, patientValue, controlValues)
            nless = sum(controlValues < patientValue);
            nequal = sum(controlValues == patientValue);
            percentile = round(100 * (nless + 0.5*nequal) / length(controlValues), 0);
            %clear nless nequal;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.IDEditField.Value); 
                msgbox('Please enter subject ID before starting')
            elseif app.UIFigure.Position(3) == [659]; %checking if current UI width is default width of UI window
                msgbox('Please maximize the window to your screen first, THEN hit start')
            else
                app.StartButton.Enable = 'off';
                app.IDEditField.Enable = 'off';
                app.SetDropDown.Enable = 'off';
                app.PhotodiodeCheckBox.Enable = 'off';
                app.MaximizewindowthenpressLabel.Visible = 'off';
                app.Instructions.Visible = 'off';
                app.ViSATTaskLabel.Text = '';
                % load block images -- all are 960 x 720 pixels
                blocknumber=app.SetDropDown.Value;
                pn=mfilename('fullpath');
                pn = pn(1:end-7);
                load([pn 'data/Set' blocknumber '.mat']) % load images for stimuli, answers, and fixation
                app.stimulusImages=stimuli; 
                app.answerImages=answers;
                app.fixationImage=fixation; % load fixation image
                f=255*ones(960,720,3,'uint8')*.97; 
                image(app.display,f); %lighter grey to reflect starting block
                %create filename under which data from this block will be saved automatically after each trial (repeatedly)
                dt=char(datetime); dt([findstr(dt,'-') findstr(dt,':')])=[]; dt(findstr(dt,' '))='_';
                app.blockDateTime=dt; clear dt
                app.ptdir=[pn app.IDEditField.Value];
                if ~exist(app.ptdir, 'dir');  mkdir(app.ptdir); end %each participant gets their own subfolder to keep data organized
                app.blockfilename=fullfile(app.ptdir, ['set' blocknumber '.mat']);
                app.csvFilename = fullfile(app.ptdir, ['set' blocknumber '.csv']);
                if exist(app.blockfilename, 'file')
                    msgbox(['Set has been ran before. To run it again, move or delete the files at ' app.blockfilename ' and ' app.csvFilename]);
                    return;
                end
                % initialize variables
                app.XYclickAnswer=nan(28,2);
                app.XYclickStimulus=nan(28,2);
                app.DurationsFixStimAns=nan(28,3);
                app.trialtimestamps=cell(28,6);
                app.userAnswers=cell(28,1);
                app.controlData = readtable(fullfile(pn, "data/controls.csv"));
                app.mturkData = readtable(fullfile(pn, "data/mturk.csv"));
                app.mturkData = mergevars(app.mturkData, ["pcons_all_1", "pcons_all_2", "pcons_all_3", "pcons_all_4"], "NewVariableName", "pcons_all");
                app.summaryFilename = fullfile(app.ptdir, 'summary.csv');
                % run trials: 3 practice followed by 25 actual trials
                for i=1:28
                    app.currenttrial=i;
                    % 1. do fixation step
                    fixationFunction(app)
                    if ~isvalid(app) 
                         msgbox('App exited during a block. Any completed trials still saved'); 
                         return; 
                    end %in case app closed (block stopped)
                    % 2. do stimulus step
                    stimulusFunction(app)
                    if ~isvalid(app)
                         msgbox('App exited during a block. Any completed trials still saved'); 
                         return; 
                    end %in case app closed (block stopped)
                    % 3. do do answer step
                    answerFunction(app)
                    if ~isvalid(app)
                         msgbox('App exited during a block. Any completed trials still saved'); 
                         return;
                    end %in case app closed (block stopped)
                    % 4. save all data
                    saveFunction(app)
                    if ~isvalid(app)
                         msgbox('App exited during a block. Any completed trials still saved');
                         return;
                    end %in case app closed (block stopped)
                end

                f=255*ones(960,720,3,'uint8')*.94;
                if app.PhotodiodeCheckBox.Value==true; app.ViSATTaskLabel.BackgroundColor=[.9 .9 .9]; else; app.ViSATTaskLabel.BackgroundColor=[.94 .94 .94]; end %f(app.photodiodepixels{1},app.photodiodepixels{2},:)=f(1,1,1)*.9; end
                image(app.display,f); %back to light grey screen
                pause(1);
                app.Instructions.Text = 'Done, great job!';
                app.Instructions.Visible = 'on';
                app.ViSATTaskLabel.Text = 'ViSAT Task';
                computeSummary(app);
            end

            app.StartButton.Enable = 'on';
            app.IDEditField.Enable = 'on';
            app.SetDropDown.Enable = 'on';
            app.PhotodiodeCheckBox.Enable = 'on';
            

        end

        % Button down function: display
        function displayButtonDown(app, event)
            x=1;
        end

        % Value changed function: PhotodiodeCheckBox
        function PhotodiodeCheckBoxValueChanged(app, event)
            %f=255*ones(960,720,3,'uint8')*.94; 
            if app.PhotodiodeCheckBox.Value==true; 
                app.ViSATTaskLabel.BackgroundColor=[.9 .9 .9]; %f(app.photodiodepixels{1},app.photodiodepixels{2},:)=f(1,1,1)*.9; 
            else
                app.ViSATTaskLabel.BackgroundColor=[.94 .94 .94]; %original app color
            end
            %image(app.display,f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.display = uiaxes(app.UIFigure);
            app.display.XTick = [];
            app.display.YTick = [];
            app.display.ButtonDownFcn = createCallbackFcn(app, @displayButtonDown, true);
            app.display.Visible = 'off';
            app.ViSATTaskLabel = uilabel(app.UIFigure);
            app.ViSATTaskLabel.Text = 'ViSAT Task';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BlockInfo = uipanel(app.UIFigure);
            app.SetDropDownLabel = uilabel(app.BlockInfo);
            app.SetDropDownLabel.Text = 'Set';
            app.SetDropDown = uidropdown(app.BlockInfo);
            app.SetDropDown.Items = {'1', '2', '3', '4'};
            app.SetDropDown.Value = '1';
            app.IDEditFieldLabel = uilabel(app.BlockInfo);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.BlockInfo, 'text');
            app.PhotodiodeCheckBox = uicheckbox(app.UIFigure);
            app.PhotodiodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhotodiodeCheckBoxValueChanged, true);
            app.PhotodiodeCheckBox.Text = 'Photodiode';
            app.Instructions = uilabel(app.UIFigure);
            app.Instructions.Text = {'Welcome to the'; 'Visual Semantic Association Task!'; ''; 'Instructions:'; 'We will first show you one image at the top of this area. Then we will show you a panel of four images below that are the answer choices. Select one of the four images that is most associated with the first image.'; ''; 'There will be 3 practice questions at first, followed immediately by 25 test questions per set. Each question is separated by an image of a black dot, and you should fixate on (look at) this dot while waiting for the next trial.'; ''; 'The reseacher will press "Start" when you are ready.'};
            app.MaximizewindowthenpressLabel = uilabel(app.UIFigure);
            app.MaximizewindowthenpressLabel.Text = 'Maximize window, then press...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViSATUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end